classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeismicsignalpolarimetricanalysisUIFigure  matlab.ui.Figure
        SensorresponseEditField         matlab.ui.control.NumericEditField
        SensorresponseEditFieldLabel    matlab.ui.control.Label
        OptimizewindowCheckBox          matlab.ui.control.CheckBox
        DecimationfactorSlider          matlab.ui.control.NumericEditField
        DecimationfactorLabel           matlab.ui.control.Label
        BandpassfilterPanel             matlab.ui.container.Panel
        UpperfrequencyHzEditField       matlab.ui.control.NumericEditField
        UpperfrequencyHzEditFieldLabel  matlab.ui.control.Label
        LowerfrequencyHzEditField       matlab.ui.control.NumericEditField
        LowerfrequencyHzEditFieldLabel  matlab.ui.control.Label
        WavetypePanel                   matlab.ui.container.Panel
        LovesmodeCheckBox               matlab.ui.control.CheckBox
        RayleighmodeCheckBox            matlab.ui.control.CheckBox
        TimeandfrequencylimitsPanel     matlab.ui.container.Panel
        ApplyButton                     matlab.ui.control.Button
        FrequencystopHzEditField        matlab.ui.control.NumericEditField
        FrequencystopHzEditFieldLabel   matlab.ui.control.Label
        FrequencystartHzEditField       matlab.ui.control.NumericEditField
        FrequencystartHzEditFieldLabel  matlab.ui.control.Label
        TimestopsEditField              matlab.ui.control.NumericEditField
        TimestopsEditFieldLabel         matlab.ui.control.Label
        TimestartsEditField             matlab.ui.control.NumericEditField
        TimestartsEditFieldLabel        matlab.ui.control.Label
        SavedataButton                  matlab.ui.control.Button
        CleardataButton                 matlab.ui.control.Button
        SaturationEditField             matlab.ui.control.NumericEditField
        SaturationEditFieldLabel        matlab.ui.control.Label
        ColormapListBox                 matlab.ui.control.ListBox
        ColormapListBoxLabel            matlab.ui.control.Label
        InterpolationButtonGroup        matlab.ui.container.ButtonGroup
        BilinearButton                  matlab.ui.control.RadioButton
        NearestButton                   matlab.ui.control.RadioButton
        Switch                          matlab.ui.control.Switch
        SamplingrateEditField           matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel      matlab.ui.control.Label
        BackazimuthdegEditField         matlab.ui.control.NumericEditField
        BackazimuthdegEditFieldLabel    matlab.ui.control.Label
        WindowtimespreadsEditField      matlab.ui.control.NumericEditField
        WindowtimespreadsEditFieldLabel  matlab.ui.control.Label
        InfoTable                       matlab.ui.control.TextArea
        InfoTextAreaLabel               matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        RunanalysisButton               matlab.ui.control.Button
        LoadsignalsButton               matlab.ui.control.Button
        S3Figure                        matlab.ui.control.UIAxes
        S2Figure                        matlab.ui.control.UIAxes
        timeDomainPlot                  matlab.ui.control.UIAxes
        S1Figure                        matlab.ui.control.UIAxes
        S0Figure                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        fontsize = 10;
        S0 = [];
        S1 = [];
        S2 = [];
        S3 = [];
        signal_EW = [];
        signal_NS = [];
        signal_VZ = [];
        signal_EW_original = [];
        signal_NS_original = [];
        signal_VZ_original = [];
        decim_factor = 20;
        fs = 100;
        fs_original = 100;
        sigma = 200;
        NFFT = 1024;
        x = [];
        method = 'FFT';
        signal_set = 0;
        t_scale = [];
        f_scale = [];
        comm_counter = 0;
        file_EW = [];
        path_EW = [];
        file_NS = [];
        path_NS = [];
        file_VZ = [];
        path_VZ = [];
        backazimuth = 0;
        signals_correct = 0;
        original_t = [];
        t = [];
        cmap = [];
        InterpolationMethod = 'nearest';
        selectedCmap = 'Default';
        saturationLevel = 0.01;
        freqStart = 0;
        freqStop = 50;
        timeStart = 0;
        timeStop = 0;
        axesSet = 0;
        lowerFilterLimit = 0.001;
        upperFilterLimit = 50;
        absoluteFilterLowerLimit = 0.001;
        stokesSet = 0;
        startPath = pwd;
    end

    properties (Access = private)
        a_path = addpath('GAB\');
        b_path = addpath('UTILS\');
    end

    methods (Access = private)

        function optimizeWindowIfEnabled(app)
            if app.OptimizewindowCheckBox.Value && app.signals_correct
                optimizeWindowSigma(app);

                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = ...
                    'Window re-optimized after parameter change';
                scroll(app.InfoTable, 'bottom');
            end
        end

        function Ren = Ren_Opt_S0(app, sigma)

            if ~isfinite(sigma) || sigma <= 0
                Ren = realmax;
                return;
            end

            if app.RayleighmodeCheckBox.Value == true
                mode = 1;
            else
                mode = 2;
            end
            ba = app.BackazimuthdegEditField.Value;
            if mode == 1
                x_rad = app.signal_NS * cosd(ba) + app.signal_EW * sind(ba);
            elseif mode == 2
                x_rad = -app.signal_NS * sind(ba) + app.signal_EW * cosd(ba);
            end

            vz = app.signal_VZ;

            x.signal = vz;
            x.N = length(vz);
            x.fs = app.fs;
            S_VZ = Gab_STFT(x, app.NFFT, sigma,0, 'FFT');
            f_scale = linspace(-x.fs/2, x.fs/2, app.NFFT);
            t_scale = app.t;

            S_VZ = S_VZ(app.NFFT/2+1:end,:);

            x.signal = x_rad;
            x.N = length(x_rad);

            S_rad = Gab_STFT(x, app.NFFT, sigma, 0, 'FFT');
            S_rad = S_rad(app.NFFT/2+1:end,:);

            S0 = abs(S_VZ).^2 + abs(S_rad).^2;
            t = 1:size(S0,2);
            f = 1:size(S0, 1);

            Ren = Renyi_Entropy(S0, t, f, 3 );
            if ~isfinite(Ren)
                Ren = realmax;
            end
        end

        function optimizeWindowSigma(app)
            sigma0 = max(app.sigma, eps);
            objective = @(logSigma)Ren_Opt_S0(app, exp(logSigma));
            options = optimset('Display', 'off', 'TolX', 1e-3);
            logSigmaOpt = fminsearch(objective, log(sigma0), options);
            app.sigma = exp(logSigmaOpt);
            app.WindowtimespreadsEditField.Value = app.sigma / app.fs;
        end

        function y = bandpassFilter(app, x, fs, fmin, fmax)
            y = x;
            nyquist = fs / 2;
            if ~isfinite(fmin) || ~isfinite(fmax)
                msg = 'ERROR: Filter frequencies must be finite.';
                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = msg;
                scroll(app.InfoTable, 'bottom');
                return;
            end

            if fmin <= 0
                msg = 'ERROR: Lower filter frequency must be greater than 0 Hz.';
                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = msg;
                scroll(app.InfoTable, 'bottom');
                return;
            end

            if fmax >= nyquist
                msg = sprintf( ...
                    ['ERROR: Upper filter frequency must be smaller than ' ...
                    'the Nyquist frequency %.6g Hz.'], ...
                    nyquist);
                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = msg;
                scroll(app.InfoTable, 'bottom');
                return;
            end

            if fmin >= fmax
                msg = ['ERROR: Lower filter frequency must be smaller ' ...
                    'than upper filter frequency.'];
                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = msg;
                scroll(app.InfoTable, 'bottom');
                return;
            end

            normalizedBand = [fmin, fmax] / nyquist;

            try
                [z, p, k] = butter(4, normalizedBand, 'bandpass');
                sos = zp2sos(z, p, k);
                y = filtfilt(sos, 1, x);

            catch ME
                msg = sprintf('ERROR: Bandpass filtering failed: %s', ME.message);
                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = msg;
                scroll(app.InfoTable, 'bottom');
                return;
            end

        end
        function recalcStokes(app)
            if app.signals_correct

                if app.RayleighmodeCheckBox.Value == true
                    mode = 1;
                else
                    mode = 2;
                end
                ba = app.BackazimuthdegEditField.Value;
                if mode == 1
                    x_rad = app.signal_NS * cosd(ba) + app.signal_EW * sind(ba);
                elseif mode == 2
                    x_rad = -app.signal_NS * sind(ba) + app.signal_EW * cosd(ba);
                end

                vz = app.signal_VZ;

                x.signal = vz;
                x.N = length(vz);
                x.fs = app.fs;
                S_VZ = Gab_STFT(x, app.NFFT, app.sigma,0, 'FFT');
                f_scale = linspace(-x.fs/2, x.fs/2, app.NFFT);
                t_scale = app.t;

                S_VZ = S_VZ(app.NFFT/2+1:end,:);

                f_scale = f_scale(app.NFFT/2+1:end);
                app.f_scale = f_scale;
                app.t_scale = t_scale;

                x.signal = x_rad;
                S_rad = Gab_STFT(x, app.NFFT, app.sigma, 0,'FFT');
                S_rad = S_rad(app.NFFT/2+1:end,:);

                S0 = abs(S_VZ).^2 + abs(S_rad).^2;
                S1 = abs(S_VZ).^2 - abs(S_rad).^2;
                S2 = 2 * real(S_VZ .* conj(S_rad));
                S3 = 2 * imag(S_VZ .* conj(S_rad));

                S1n = S1 ./ S0;
                S2n = S2 ./ S0;
                S3n = S3 ./ S0;
                S0n = S0./max(max(S0));

                app.S0 = S0n;
                app.S1 = S1n;
                app.S2 = S2n;
                app.S3 = S3n;
                if app.stokesSet == 1
                    app.TimestartsEditField.Limits = [min(app.t_scale) max(app.t_scale)];
                    app.TimestopsEditField.Limits = [min(app.t_scale) max(app.t_scale)];
                    app.FrequencystartHzEditField.Limits = [min(app.f_scale) max(app.f_scale)];
                    app.FrequencystopHzEditField.Limits = [min(app.f_scale) max(app.f_scale)];
                else
                    app.TimestartsEditField.Limits = [min(app.t_scale) max(app.t_scale)];
                    app.TimestopsEditField.Limits = [min(app.t_scale) max(app.t_scale)];
                    app.TimestartsEditField.Value = min(app.t_scale);
                    app.TimestopsEditField.Value = max(app.t_scale);

                    app.FrequencystartHzEditField.Limits = [min(app.f_scale) max(app.f_scale)];
                    app.FrequencystopHzEditField.Limits = [min(app.f_scale) max(app.f_scale)];
                    app.FrequencystartHzEditField.Value = min(app.f_scale);
                    app.FrequencystopHzEditField.Value = max(app.f_scale);
                end

                %%
                if app.axesSet == 0
                    app.TimestartsEditField.Limits = [min(app.t) max(app.t)];
                    app.TimestopsEditField.Limits = [min(app.t) max(app.t)];
                    app.TimestartsEditField.Value = 0;
                    app.TimestopsEditField.Value = max(app.t);
                    app.timeStart = min(app.t);
                    app.timeStop = max(app.t);

                    app.FrequencystartHzEditField.Limits = [min(app.f_scale) max(app.f_scale)];
                    app.FrequencystopHzEditField.Limits = [min(app.f_scale) max(app.f_scale)];
                    app.FrequencystartHzEditField.Value = min(app.f_scale);
                    app.FrequencystopHzEditField.Value = max(app.f_scale);

                    app.freqStart = min(app.f_scale);
                    app.freqStop = max(app.f_scale);
                    app.timeStart = min(app.t);
                    app.timeStop = max(app.t);

                end
                %%
                app.S0Figure.Visible = "on";

                imagesc(app.S0Figure,app.t_scale, app.f_scale, app.S0,'interpolation',app.InterpolationMethod);
                ax = app.S0Figure;
                styleAxes(app, ax);
                set(app.S0Figure,'ydir');
                set(app.S0Figure,'ydir','normal');
                xlabel(app.S0Figure,'Time [s]');
                ylabel(app.S0Figure,'Frequency [Hz]');
                title(app.S0Figure,'$S_0$','Interpreter','latex');
                clim(app.S0Figure, [0 1]);

                if strcmp(app.selectedCmap,'Default')
                    colormap(app.S0Figure,'turbo');
                else
                    colormap(app.S0Figure,app.selectedCmap);
                end
                c=colorbar(app.S0Figure);
                c.Label.Interpreter = 'latex';
                c.TickLabelInterpreter = 'none';
                c.Label.String = 'Energy';
                c.FontSize = 10;
                threshold = mag2db(app.saturationLevel);

                max_energy = (max(max(S0n)));
                d = db(S0n/max_energy);
                q = (threshold - d) / (threshold);
                axis(app.S0Figure, 'tight')
                set(app.S0Figure, 'YDir', 'normal')
                app.S0Figure.ActivePositionProperty = 'outerposition';
                app.S0Figure.Toolbar.Visible = 'on';
                app.S0Figure.XLim = [app.timeStart, app.timeStop];
                app.S0Figure.YLim = [app.freqStart, app.freqStop];
                %%
                app.S1Figure.Visible = "on";

                img = imagesc(app.S1Figure,app.t_scale, app.f_scale, app.S1,'interpolation',app.InterpolationMethod);
                ax = app.S1Figure;
                styleAxes(app, ax);
                set(app.S1Figure,'ydir');
                set(app.S1Figure,'ydir','normal');
                xlabel(app.S1Figure,'Time [s]');
                ylabel(app.S1Figure,'Frequency [Hz]');
                title(app.S1Figure,'$S_1$','Interpreter','latex');
                clim(app.S1Figure, [-1 1]);

                if strcmp(app.selectedCmap,'Default')
                    colormap(app.S1Figure,app.cmap);
                else
                    colormap(app.S1Figure,app.selectedCmap);
                end

                c=colorbar(app.S1Figure);
                c.Label.Interpreter = 'latex';
                c.TickLabelInterpreter = 'none';
                c.Label.String = '$S_1$';
                c.FontSize = 10;
                img.AlphaData = q;
                axis(app.S1Figure, 'tight');
                set(app.S1Figure, 'YDir', 'normal');
                app.S1Figure.ActivePositionProperty = 'outerposition';
                app.S1Figure.Toolbar.Visible = 'on';
                app.S1Figure.XLim = [app.timeStart, app.timeStop];
                app.S1Figure.YLim = [app.freqStart, app.freqStop];
                %%
                app.S2Figure.Visible = "on";

                img=imagesc(app.S2Figure,app.t_scale, app.f_scale, app.S2,'interpolation',app.InterpolationMethod);
                ax = app.S2Figure;
                styleAxes(app, ax);
                set(app.S2Figure,'ydir');
                set(app.S2Figure,'ydir','normal');
                xlabel(app.S2Figure,'Time [s]');
                ylabel(app.S2Figure,'Frequency [Hz]');
                title(app.S2Figure,'$S_2$','Interpreter','latex');
                clim(app.S2Figure, [-1 1]);

                if strcmp(app.selectedCmap,'Default')
                    colormap(app.S2Figure,app.cmap);
                else
                    colormap(app.S2Figure,app.selectedCmap);
                end
                c=colorbar(app.S2Figure);
                c.Label.Interpreter = 'latex';
                c.TickLabelInterpreter = 'none';
                c.Label.String = '$S_2$';
                c.FontSize = 10;
                img.AlphaData = q;
                axis(app.S2Figure, 'tight');
                set(app.S2Figure, 'YDir', 'normal');
                app.S2Figure.ActivePositionProperty = 'outerposition';
                app.S2Figure.Toolbar.Visible = 'on';
                app.S2Figure.XLim = [app.timeStart, app.timeStop];
                app.S2Figure.YLim = [app.freqStart, app.freqStop];
                %%
                app.S3Figure.Visible = "on";

                img=imagesc(app.S3Figure,app.t_scale, app.f_scale, app.S3,'interpolation',app.InterpolationMethod);
                ax = app.S3Figure;
                styleAxes(app, ax);
                set(app.S3Figure,'ydir');
                set(app.S3Figure,'ydir','normal');
                xlabel(app.S3Figure,'Time [s]');
                ylabel(app.S3Figure,'Frequency [Hz]');
                title(app.S3Figure,'$S_3$','Interpreter','latex');
                clim(app.S3Figure, [-1 1]);

                if strcmp(app.selectedCmap,'Default')
                    colormap(app.S3Figure,app.cmap);
                else
                    colormap(app.S3Figure,app.selectedCmap);
                end
                c=colorbar(app.S3Figure);
                c.Label.Interpreter = 'latex';
                c.TickLabelInterpreter = 'none';
                c.Label.String = '$S_3$';
                c.FontSize = 10;
                img.AlphaData = q;
                axis(app.S3Figure, 'tight');
                set(app.S3Figure, 'YDir', 'normal');
                app.S3Figure.ActivePositionProperty = 'outerposition';
                app.S3Figure.Toolbar.Visible = 'on';
                app.S3Figure.XLim = [app.timeStart, app.timeStop];
                app.S3Figure.YLim = [app.freqStart, app.freqStop];

                app.SavedataButton.Enable = 'on';
                app.SavedataButton.Visible = 'on';
                app.TimeandfrequencylimitsPanel.Enable = 'on';
                app.TimeandfrequencylimitsPanel.Visible = "on";

                app.stokesSet = 1;


            else
                app.stokesSet = 0;
                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = sprintf('The signals must be loaded!');
                scroll(app.InfoTable, 'bottom');
                app.SavedataButton.Enable = 'off';
                app.SavedataButton.Visible = 'off';
            end
        end

        function styleAxes(app, ax)

            ax.FontName = 'Arial';
            ax.FontSize = 10;

            ax.FontUnits = 'points';
            ax.Units = 'normalized';
            ax.PositionConstraint = 'outerposition';

            ax.TickLabelInterpreter = 'none';

            ax.Title.Interpreter = 'none';
            ax.XLabel.Interpreter = 'none';
            ax.YLabel.Interpreter = 'none';

            ax.Title.FontSize = 12;

        end

        function updateTimePlot(app)
            app.t = (0:length(app.signal_EW)-1) ./ app.fs;
            app.original_t = app.t;
            sensorResponse = app.SensorresponseEditField.Value;
            app.timeDomainPlot.Visible = true;
            cla(app.timeDomainPlot);
            plot(app.timeDomainPlot, app.t, app.signal_EW .* sensorResponse, 'Color', 'k');
            hold(app.timeDomainPlot, 'on');
            plot(app.timeDomainPlot, app.t, app.signal_NS .* sensorResponse, 'Color', 'r');
            plot(app.timeDomainPlot, app.t, app.signal_VZ .* sensorResponse, 'Color', 'b');
            hold(app.timeDomainPlot, 'off');
            styleAxes(app, app.timeDomainPlot);
            legend(app.timeDomainPlot, 'EW', 'NS', 'VZ', 'Interpreter', 'none');
            xlabel(app.timeDomainPlot, 'Time [s]', 'Interpreter', 'none');
            ylabel(app.timeDomainPlot, 'Amplitude [V/s]', 'Interpreter', 'none');
            title(app.timeDomainPlot, 'Time-domain plot', 'Interpreter', 'none');
            grid(app.timeDomainPlot, 'on');
            grid(app.timeDomainPlot, 'minor');
        end

        function updateFrequencyPlot(app)   
            N = length(app.signal_EW);
            f = (0:floor(N/2)) .* app.fs ./ N;
            sensorResponse = app.SensorresponseEditField.Value;
            EW_fft = fft(app.signal_EW .* sensorResponse) ./ N;
            NS_fft = fft(app.signal_NS .* sensorResponse) ./ N;
            VZ_fft = fft(app.signal_VZ .* sensorResponse) ./ N;
        
            EW_fft = abs(EW_fft(1:length(f)));
            NS_fft = abs(NS_fft(1:length(f)));
            VZ_fft = abs(VZ_fft(1:length(f)));
        
            if rem(N, 2) == 0
                idx = 2:length(f)-1;
            else
                idx = 2:length(f);
            end
        
            EW_fft(idx) = 2 .* EW_fft(idx);
            NS_fft(idx) = 2 .* NS_fft(idx);
            VZ_fft(idx) = 2 .* VZ_fft(idx);
        
            EW_fft = mag2db(max(EW_fft, eps));
            NS_fft = mag2db(max(NS_fft, eps));
            VZ_fft = mag2db(max(VZ_fft, eps));
        
            cla(app.timeDomainPlot);
        
            plot(app.timeDomainPlot, f, EW_fft, 'k');
            hold(app.timeDomainPlot, 'on');
            plot(app.timeDomainPlot, f, NS_fft, 'r');
            plot(app.timeDomainPlot, f, VZ_fft, 'b');
            hold(app.timeDomainPlot, 'off');
      
            styleAxes(app, app.timeDomainPlot);
            legend(app.timeDomainPlot, 'EW', 'NS', 'VZ', 'Interpreter', 'none');
            xlabel(app.timeDomainPlot, 'Frequency [Hz]', 'Interpreter', 'none');
            ylabel(app.timeDomainPlot, 'Magnitude [dB]', 'Interpreter', 'none');
            title(app.timeDomainPlot, 'Frequency-domain plot', 'Interpreter', 'none');
            grid(app.timeDomainPlot, 'on');
            grid(app.timeDomainPlot, 'minor');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fig = app.SeismicsignalpolarimetricanalysisUIFigure;

            fig.WindowState = 'normal';
            drawnow;
            screenPos = get(groot, 'ScreenSize');
            marginLeft   = 30;
            marginRight  = 30;
            marginBottom = 100;
            marginTop    = 80;

            availableWidth = screenPos(3) - marginLeft - marginRight;
            availableHeight = screenPos(4) - marginBottom - marginTop;

            figPos = fig.Position;

            scale = min([ ...
                1, ...
                availableWidth / figPos(3), ...
                availableHeight / figPos(4)]);

            figPos(3) = floor(figPos(3) * scale);
            figPos(4) = floor(figPos(4) * scale);

            figPos(1) = screenPos(1) + marginLeft + (availableWidth - figPos(3)) / 2;
            figPos(2) = screenPos(2) + marginBottom + (availableHeight - figPos(4)) / 2;
            fig.Position = round(figPos);

            drawnow;
            movegui(fig, 'onscreen');
        end

        % Button pushed function: LoadsignalsButton
        function LoadsignalsButtonPushed(app, event)
            if isempty(app.cmap)
                matFile = fullfile(fileparts(mfilename('fullpath')), ...
                    'colormap_MM.mat');

                if ~isfile(matFile)
                    error('Nie znaleziono pliku: %s', matFile);
                end

                cmapData = load(matFile);
                variableNames = fieldnames(cmapData);

                if isempty(variableNames)
                    error('Plik colormap_MM.mat nie zawiera żadnej zmiennej.');
                end

                app.cmap = cmapData.(variableNames{1});
            end

            app.selectedCmap = 'Default';
            app.ColormapListBox.Value = 'Default';

            length_flag = 1;
            while length_flag

                app.decim_factor = 20;
                app.DecimationfactorSlider.Value = app.decim_factor;
                app.startPath = pwd;
                [app.file_EW, app.path_EW] = uigetfile( ...
                    fullfile(app.startPath, '*.asc'), ...
                    'Select EW component', ...
                    'MultiSelect', 'off');

                if isequal(app.file_EW, 0)
                    return;
                end

                app.startPath = app.path_EW;

                T = readtable(fullfile(app.path_EW, app.file_EW));
                app.signal_EW = table2array(T);
                app.signal_EW_original = app.signal_EW;
                app.signal_EW = decimate(app.signal_EW_original, app.decim_factor);

                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = 'EW component loaded';
                scroll(app.InfoTable, 'bottom');

                [app.file_NS, app.path_NS] = uigetfile( ...
                    fullfile(app.startPath, '*.asc'), ...
                    'Select NS component', ...
                    'MultiSelect', 'off');

                if isequal(app.file_NS, 0)
                    return;
                end

                T = readtable(fullfile(app.path_NS, app.file_NS));
                app.signal_NS = table2array(T);
                app.signal_NS_original = app.signal_NS;
                app.signal_NS = decimate(app.signal_NS_original, app.decim_factor);

                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = 'NS component loaded';
                scroll(app.InfoTable, 'bottom');

                [app.file_VZ, app.path_VZ] = uigetfile( ...
                    fullfile(app.startPath, '*.asc'), ...
                    'Select VZ component', ...
                    'MultiSelect', 'off');

                if isequal(app.file_VZ, 0)
                    return;
                end

                T = readtable(fullfile(app.path_VZ, app.file_VZ));
                app.signal_VZ = table2array(T);
                app.signal_VZ_original = app.signal_VZ;
                app.signal_VZ = decimate(app.signal_VZ_original, app.decim_factor);

                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = 'VZ component loaded';
                scroll(app.InfoTable, 'bottom');

                figure(app.SeismicsignalpolarimetricanalysisUIFigure);

                app.stokesSet = 0;

                app.fs_original = app.SamplingrateEditField.Value;
                app.fs = app.SamplingrateEditField.Value / app.decim_factor;

                app.sigma = app.WindowtimespreadsEditField.Value * app.fs;

                A = length(app.signal_EW);
                B = length(app.signal_NS);
                C = length(app.signal_VZ);

                if A ~= B || A ~= C || B ~= C
                    app.comm_counter = app.comm_counter + 1;
                    app.InfoTable.Value{app.comm_counter} = sprintf('Signals must have the same length');
                    scroll(app.InfoTable, 'bottom');
                else
                    length_flag = 0;
                    app.signals_correct = 1;
                end
            end
            app.DecimationfactorSlider.Enable = "on";
            app.SamplingrateEditField.Enable = "on";
            app.RunanalysisButton.Enable = "on";
            app.BackazimuthdegEditField.Enable = "on";
            app.WindowtimespreadsEditField.Enable = "on";
            app.LovesmodeCheckBox.Enable = "on";
            app.RayleighmodeCheckBox.Enable = "on";
            app.Switch.Enable = 'on';
            app.CleardataButton.Enable = "on";
            app.WavetypePanel.Visible = 'on';
            app.WavetypePanel.Enable = "on";
            app.BandpassfilterPanel.Enable = 'on';

            nyquist = app.fs / 2;
            maxFilterFrequency = 0.99 * nyquist;

            app.lowerFilterLimit = app.absoluteFilterLowerLimit;
            app.upperFilterLimit = maxFilterFrequency;

            app.LowerfrequencyHzEditField.Limits = [app.absoluteFilterLowerLimit, maxFilterFrequency];

            app.UpperfrequencyHzEditField.Limits = ...
                [app.absoluteFilterLowerLimit, maxFilterFrequency];

            app.LowerfrequencyHzEditField.Value = app.lowerFilterLimit;
            app.UpperfrequencyHzEditField.Value = app.upperFilterLimit;

            app.InterpolationButtonGroup.Visible = 'off';
            app.SaturationEditField.Visible = "off";
            app.SaturationEditFieldLabel.Visible = "off";
            app.TimeandfrequencylimitsPanel.Visible = "off";
            app.ColormapListBox.Visible = "off";
            app.ColormapListBoxLabel.Visible ="off";
            app.SavedataButton.Visible = "off";
            app.OptimizewindowCheckBox.Enable = "on";
            app.OptimizewindowCheckBox.Value = false;

            app.t = 0:length(app.signal_EW)-1;
            app.t = app.t ./ app.fs;
            app.original_t = app.t;
            app.timeDomainPlot.Visible = true;
            cla(app.timeDomainPlot);
            plot(app.t, app.signal_EW.*app.SensorresponseEditField.Value,'Parent',app.timeDomainPlot,'Color','k')
            ax = app.timeDomainPlot;
            styleAxes(app, ax);
            hold(app.timeDomainPlot,'on')
            plot(app.t, app.signal_NS .* app.SensorresponseEditField.Value,'Parent',app.timeDomainPlot,'Color','r')
            plot(app.t, app.signal_VZ .* app.SensorresponseEditField.Value,'Parent',app.timeDomainPlot,'Color','b')
            legend(app.timeDomainPlot,'EW','NS','VZ', 'Interpreter', 'none')
            xlabel(app.timeDomainPlot,'Time [s]', 'Interpreter', 'none')
            ylabel(app.timeDomainPlot, 'Amplitude [V/s]', 'Interpreter', 'none')
            grid(app.timeDomainPlot,'on');
            grid(app.timeDomainPlot,'minor');
            title(app.timeDomainPlot,'Time-domain plot', 'Interpreter', 'none');

            cla(app.S0Figure);
            colorbar(app.S0Figure,'off');
            cla(app.S1Figure);
            colorbar(app.S1Figure,'off');
            cla(app.S2Figure);
            colorbar(app.S2Figure,'off');
            cla(app.S3Figure);
            colorbar(app.S3Figure,'off');
            app.S0Figure.Visible = 'off';
            app.S1Figure.Visible = 'off';
            app.S2Figure.Visible = 'off';
            app.S3Figure.Visible = 'off';
            app.axesSet = 0;

        end

        % Value changed function: SamplingrateEditField
        function SamplingrateEditFieldValueChanged(app, event)
            sigmaSeconds = app.WindowtimespreadsEditField.Value;
            value = app.SamplingrateEditField.Value;
            app.fs_original = value;
            app.fs = value / app.decim_factor;
            app.sigma = sigmaSeconds * app.fs;

            optimizeWindowIfEnabled(app);

            app.axesSet = 0;
            recalcStokes(app);
            app.RunanalysisButton.Enable = "off";
        end

        % Button pushed function: RunanalysisButton
        function RunanalysisButtonPushed(app, event)
            recalcStokes(app);
            app.InterpolationButtonGroup.Visible = 'on';
            app.ColormapListBox.Visible = 'on';
            app.ColormapListBoxLabel.Visible = 'on';
            app.SaturationEditField.Visible = 'on';
            app.SaturationEditFieldLabel.Visible = 'on';
            app.RunanalysisButton.Enable = "off";
        end

        % Value changed function: LovesmodeCheckBox
        function LovesmodeCheckBoxValueChanged(app, event)
            value = app.LovesmodeCheckBox.Value;
            app.RayleighmodeCheckBox.Value = ~value;
            optimizeWindowIfEnabled(app);
            recalcStokes(app);
            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = sprintf('Love''s mode on');
            scroll(app.InfoTable, 'bottom');
            app.RunanalysisButton.Enable = "off";
        end

        % Value changed function: RayleighmodeCheckBox
        function RayleighmodeCheckBoxValueChanged(app, event)
            value = app.RayleighmodeCheckBox.Value;
            app.LovesmodeCheckBox.Value = ~value;
            optimizeWindowIfEnabled(app);
            recalcStokes(app);
            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = sprintf('Rayleigh mode on');
            scroll(app.InfoTable, 'bottom');
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
        end

        % Value changed function: BackazimuthdegEditField
        function BackazimuthdegEditFieldValueChanged(app, event)
            value = app.BackazimuthdegEditField.Value;
            app.backazimuth = value;
            optimizeWindowIfEnabled(app);
            recalcStokes(app);
            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = sprintf('Backazimuth changed');
            scroll(app.InfoTable, 'bottom');
        end

        % Value changed function: WindowtimespreadsEditField
        function WindowtimespreadsEditFieldValueChanged(app, event)
            value = app.WindowtimespreadsEditField.Value;
            app.sigma = value*app.fs;
            recalcStokes(app);
            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = sprintf('Window time spread changed');
            scroll(app.InfoTable, 'bottom');
        end

        % Value changed function: DecimationfactorSlider
        function DecimationfactorSliderValueChanged(app, event)
            % Value changed function: DecimationfactorSliderFopti

                % Odczyt i zapis nowego współczynnika decymacji
                value = round(app.DecimationfactorSlider.Value);
                app.DecimationfactorSlider.Value = value;
                app.decim_factor = value;

                % Zachowanie bieżącej szerokości okna w sekundach
                sigmaSeconds = app.WindowtimespreadsEditField.Value;

                % Nowa częstotliwość próbkowania
                app.fs = app.fs_original / app.decim_factor;

                % Nowe ograniczenia filtra
                nyquist = app.fs / 2;
                maxFilterFrequency = 0.99 * nyquist;

                if app.lowerFilterLimit >= maxFilterFrequency
                    app.lowerFilterLimit = app.absoluteFilterLowerLimit;
                end

                % Po zmianie decymacji górna granica jest ustawiana
                % na 99% nowej częstotliwości Nyquista
                app.upperFilterLimit = maxFilterFrequency;

                % Tymczasowe rozszerzenie limitów chroni przed błędem,
                % gdy nowa wartość leży poza poprzednimi Limits
                app.LowerfrequencyHzEditField.Limits = ...
                    [app.absoluteFilterLowerLimit, Inf];

                app.UpperfrequencyHzEditField.Limits = ...
                    [app.absoluteFilterLowerLimit, Inf];

                app.LowerfrequencyHzEditField.Value = ...
                    app.lowerFilterLimit;

                app.UpperfrequencyHzEditField.Value = ...
                    app.upperFilterLimit;

                % Ustawienie nowych limitów kontrolek
                app.LowerfrequencyHzEditField.Limits = ...
                    [app.absoluteFilterLowerLimit, maxFilterFrequency];

                app.UpperfrequencyHzEditField.Limits = ...
                    [app.absoluteFilterLowerLimit, maxFilterFrequency];

                % Ponowna decymacja i filtracja EW
                app.signal_EW = decimate( ...
                    app.signal_EW_original, app.decim_factor);

                app.signal_EW = bandpassFilter( ...
                    app, app.signal_EW, app.fs, ...
                    app.lowerFilterLimit, app.upperFilterLimit);

                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = ...
                    'EW component decimated and filtered';
                scroll(app.InfoTable, 'bottom');

                % Ponowna decymacja i filtracja NS
                app.signal_NS = decimate( ...
                    app.signal_NS_original, app.decim_factor);

                app.signal_NS = bandpassFilter( ...
                    app, app.signal_NS, app.fs, ...
                    app.lowerFilterLimit, app.upperFilterLimit);

                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = ...
                    'NS component decimated and filtered';
                scroll(app.InfoTable, 'bottom');

                % Ponowna decymacja i filtracja VZ
                app.signal_VZ = decimate( ...
                    app.signal_VZ_original, app.decim_factor);

                app.signal_VZ = bandpassFilter( ...
                    app, app.signal_VZ, app.fs, ...
                    app.lowerFilterLimit, app.upperFilterLimit);

                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = ...
                    'VZ component decimated and filtered';
                scroll(app.InfoTable, 'bottom');

                % Aktualizacja osi czasu
                app.t = (0:length(app.signal_EW)-1) ./ app.fs;
                app.original_t = app.t;
                app.axesSet = 0;

                % Przeliczenie szerokości okna z sekund na próbki
                app.sigma = sigmaSeconds * app.fs;

                % Ponowna optymalizacja, jeśli opcja jest zaznaczona
                optimizeWindowIfEnabled(app);

                % Aktualizacja wykresu sygnałów
                if strcmp(app.Switch.Value, 'Time domain')
                    updateTimePlot(app);
                else
                    updateFrequencyPlot(app);
                end

                % Ponowne obliczenie parametrów Stokesa
                if app.stokesSet == 1
                    recalcStokes(app);
                end

                app.RunanalysisButton.Enable = "off";
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if app.signals_correct
                if strcmp(value, 'Time domain')
                    updateTimePlot(app);
                else
                    updateFrequencyPlot(app);
                end
            else
            end
        end

        % Selection changed function: InterpolationButtonGroup
        function InterpolationButtonGroupSelectionChanged(app, event)
            selectedButton = app.InterpolationButtonGroup.SelectedObject;

            if selectedButton == app.NearestButton
                app.InterpolationMethod = 'nearest';

            elseif selectedButton == app.BilinearButton
                app.InterpolationMethod = 'bilinear';
            end
            recalcStokes(app);
            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = sprintf('Interpolation method changed');
            scroll(app.InfoTable, 'bottom');
        end

        % Value changed function: ColormapListBox
        function ColormapListBoxValueChanged(app, event)
            value = app.ColormapListBox.Value;
            app.selectedCmap = value;
            recalcStokes(app);
            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = sprintf('Colormap changed');
            scroll(app.InfoTable, 'bottom');
        end

        % Value changed function: SaturationEditField
        function SaturationEditFieldValueChanged(app, event)
            value = app.SaturationEditField.Value;
            app.saturationLevel = value;
            recalcStokes(app);
            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = sprintf('Saturation level changed');
            scroll(app.InfoTable, 'bottom');
        end

        % Button pushed function: CleardataButton
        function CleardataButtonPushed(app, event)

            app.InfoTable.Value = {''};
            app.comm_counter = 0;

            app.fontsize = 10;
            app.S0 = [];
            app.S1 = [];
            app.S2 = [];
            app.S3 = [];
            app.signal_EW = [];
            app.signal_NS = [];
            app.signal_VZ = [];
            app.signal_EW_original = [];
            app.signal_NS_original = [];
            app.signal_VZ_original = [];
            app.decim_factor = 20;
            app.fs = 100;
            app.fs_original = 100;
            app.sigma = 200;
            app.NFFT = 1024;
            app.x = [];
            app.method = 'FFT';
            app.signal_set = 0;
            app.t_scale = [];
            app.f_scale = [];
            app.file_EW = [];
            app.path_EW = [];
            app.file_NS = [];
            app.path_NS = [];
            app.file_VZ = [];
            app.path_VZ = [];
            app.backazimuth = 0;
            app.signals_correct = 0;
            app.original_t = [];
            app.t = [];
            app.stokesSet = 0;

            app.DecimationfactorSlider.Enable = "off";
            app.SamplingrateEditField.Enable = "off";
            app.SamplingrateEditField.Value = app.fs_original;
            app.RunanalysisButton.Enable = "off";
            app.BackazimuthdegEditField.Enable = "off";
            app.WindowtimespreadsEditField.Enable = "off";
            app.fs = app.fs_original/app.decim_factor;
            app.WindowtimespreadsEditField.Value = app.sigma/app.fs;
            app.LovesmodeCheckBox.Enable = "off";
            app.RayleighmodeCheckBox.Enable = "off";
            app.Switch.Enable = 'off';
            app.InterpolationButtonGroup.Visible = 'off';
            app.ColormapListBox.Visible = 'off';
            app.ColormapListBoxLabel.Visible = 'off';
            app.SaturationEditField.Visible = 'off';
            app.SaturationEditFieldLabel.Visible = 'off';
            app.CleardataButton.Enable = "off";
            app.TimeandfrequencylimitsPanel.Enable = 'off';
            app.TimeandfrequencylimitsPanel.Visible = 'off';
            app.WavetypePanel.Visible = 'off';
            app.BandpassfilterPanel.Enable = 'off';
            app.OptimizewindowCheckBox.Enable = "off";
            app.OptimizewindowCheckBox.Value = false;

            app.LowerfrequencyHzEditField.Value = app.absoluteFilterLowerLimit;
            app.UpperfrequencyHzEditField.Value = app.absoluteFilterLowerLimit;

            set(groot, 'defaultAxesFontSize', 10);
            set(groot, 'defaultTextFontSize', 10);
            set(groot, 'defaultUIControlFontSize', 10);
            ax = app.S0Figure;
            styleAxes(app, ax);
            app.S0Figure.Visible = "off";
            app.S0Figure.Toolbar.Visible = 'off';
            ax = app.S1Figure;
            styleAxes(app, ax);
            app.S1Figure.Visible = "off";
            app.S1Figure.Toolbar.Visible = 'off';
            ax = app.S2Figure;
            styleAxes(app, ax);
            app.S2Figure.Visible = "off";
            app.S2Figure.Toolbar.Visible = 'off';
            ax = app.S3Figure;
            styleAxes(app, ax);
            app.S3Figure.Visible = "off";
            app.S3Figure.Toolbar.Visible = 'off';
            ax = app.timeDomainPlot;
            styleAxes(app, ax);
            app.timeDomainPlot.Visible = false;
            legend(app.timeDomainPlot, 'off');
            Init_Env(app.fontsize,1);
            cla(app.timeDomainPlot);
            cla(app.S0Figure);
            colorbar(app.S0Figure,'off');
            cla(app.S1Figure);
            colorbar(app.S1Figure,'off');
            cla(app.S2Figure);
            colorbar(app.S2Figure,'off');
            cla(app.S3Figure);
            colorbar(app.S3Figure,'off');
            app.SavedataButton.Enable = 'off';
            app.SavedataButton.Visible = 'off';
            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = sprintf('Signals removed');
            scroll(app.InfoTable, 'bottom');
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            if ~app.stokesSet
                return;
            end

            [file, path] = uiputfile('*.csv', 'Save TFR ROI');

            if isequal(file, 0)
                return;
            end

            S = {app.S0, app.S1, app.S2, app.S3};
            names = {'S0', 'S1', 'S2', 'S3'};

            tMin = app.TimestartsEditField.Value;
            tMax = app.TimestopsEditField.Value;
            fMin = app.FrequencystartHzEditField.Value;
            fMax = app.FrequencystopHzEditField.Value;

            tMask = (app.t_scale >= tMin) & (app.t_scale <= tMax);

            fMask = (app.f_scale >= fMin) & (app.f_scale <= fMax);

            tSel = app.t_scale(tMask);
            fSel = app.f_scale(fMask);

            [T, F] = meshgrid(tSel, fSel);

            [~, baseName, ~] = fileparts(file);

            if app.RayleighmodeCheckBox.Value
                rayleighMode = 'On';
            else
                rayleighMode = 'Off';
            end

            if app.LovesmodeCheckBox.Value
                loveMode = 'On';
            else
                loveMode = 'Off';
            end

            for k = 1:4
                Sk_roi = S{k}(fMask, tMask);
                tbl = table( ...
                    T(:), ...
                    F(:), ...
                    Sk_roi(:), ...
                    'VariableNames', ...
                    {'Time', 'Frequency', 'Amplitude'});

                filename = fullfile( ...
                    path, ...
                    sprintf('%s_%s.csv', baseName, names{k}));

                fid = fopen(filename, 'w');
                if fid == -1
                    error('Cannot create file: %s', filename);
                end
                fprintf(fid, 'Metadata;Value\n');
                fprintf(fid, 'Sampling rate [Hz];%.15g\n', app.SamplingrateEditField.Value);
                fprintf(fid, 'Decimation factor;%.15g\n', app.DecimationfactorSlider.Value);
                fprintf(fid, 'Backazimuth [deg];%.15g\n', app.BackazimuthdegEditField.Value);
                fprintf(fid, 'Rayleigh mode;%s\n', rayleighMode);
                fprintf(fid, 'Love mode;%s\n', loveMode);
                fprintf(fid, 'Window time spread [s];%.15g\n', app.WindowtimespreadsEditField.Value);
                fprintf(fid, 'Lower frequency limit [Hz];%.15g\n', app.LowerfrequencyHzEditField.Value);
                fprintf(fid, 'Upper frequency limit [Hz];%.15g\n', app.UpperfrequencyHzEditField.Value);
                fprintf(fid, '\n');
                fclose(fid);

                writetable( ...
                    tbl, ...
                    filename, ...
                    'Delimiter', ';', ...
                    'WriteMode', 'append');
            end

            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = sprintf('Data successfully written');
            scroll(app.InfoTable, 'bottom');
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            newFreqStart = app.FrequencystartHzEditField.Value;
            newFreqStop  = app.FrequencystopHzEditField.Value;
            newTimeStart = app.TimestartsEditField.Value;
            newTimeStop  = app.TimestopsEditField.Value;

            if newFreqStart >= newFreqStop
                msg = 'ERROR: Frequency start must be smaller than frequency stop.';
                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = msg;
                scroll(app.InfoTable,'bottom');
                return;
            elseif newTimeStart >= newTimeStop
                msg = 'ERROR: Time start must be smaller than time stop.';
                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = msg;
                scroll(app.InfoTable,'bottom');
                return
            end

            app.freqStart = newFreqStart;
            app.freqStop  = newFreqStop;
            app.timeStart = newTimeStart;
            app.timeStop  = newTimeStop;

            app.axesSet = 1;

            recalcStokes(app);

            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = ...
                'Time and frequency limits updated';
            scroll(app.InfoTable,'bottom');

        end

        % Value changed function: LowerfrequencyHzEditField
        function LowerfrequencyHzEditFieldValueChanged(app, event)
            newLower = app.LowerfrequencyHzEditField.Value;
            upper = app.upperFilterLimit;
            nyquist = app.fs / 2;
            if newLower <= 0 || newLower >= upper || upper >= nyquist
                app.LowerfrequencyHzEditField.Value = app.lowerFilterLimit;

                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = sprintf( ...
                    ['ERROR: Filter limits must satisfy ' ...
                    '0 < f_{low} < f_{high} < %.4f Hz.'], ...
                    nyquist);

                scroll(app.InfoTable, 'bottom');
                return;
            end
            app.lowerFilterLimit = newLower;
            dec = app.decim_factor;
            signal_EW = decimate(app.signal_EW_original, dec);
            app.signal_EW = bandpassFilter(app, signal_EW, app.fs, app.lowerFilterLimit, app.upperFilterLimit);

            signal_NS = decimate(app.signal_NS_original, dec);
            app.signal_NS = bandpassFilter(app, signal_NS, app.fs, app.lowerFilterLimit, app.upperFilterLimit);

            signal_VZ = decimate(app.signal_VZ_original, dec);
            app.signal_VZ = bandpassFilter(app, signal_VZ, app.fs, app.lowerFilterLimit, app.upperFilterLimit);

            if strcmp(app.Switch.Value, 'Time domain')
                updateTimePlot(app);
            else
                updateFrequencyPlot(app);
            end

            optimizeWindowIfEnabled(app);

            if app.stokesSet == 1
                recalcStokes(app);
            end

        end

        % Value changed function: UpperfrequencyHzEditField
        function UpperfrequencyHzEditFieldValueChanged(app, event)
            value = app.UpperfrequencyHzEditField.Value;

            app.upperFilterLimit = value;
            dec = app.decim_factor;
            signal_EW = decimate(app.signal_EW_original,dec);
            signal_EW = bandpassFilter(app, signal_EW, app.fs, app.lowerFilterLimit, value);
            app.signal_EW = signal_EW;

            signal_NS = decimate(app.signal_NS_original,dec);
            signal_NS = bandpassFilter(app, signal_NS, app.fs, app.lowerFilterLimit, value);
            app.signal_NS = signal_NS;

            signal_VZ = decimate(app.signal_VZ_original,dec);
            signal_VZ = bandpassFilter(app, signal_VZ, app.fs, app.lowerFilterLimit, value);
            app.signal_VZ = signal_VZ;

            if strcmp(app.Switch.Value, 'Time domain')
                updateTimePlot(app);
            else
                updateFrequencyPlot(app);
            end

            optimizeWindowIfEnabled(app);

            if app.stokesSet == 1
                recalcStokes(app);
            end
        end

        % Value changed function: OptimizewindowCheckBox
        function OptimizewindowCheckBoxValueChanged(app, event)
            optimizeEnabled = app.OptimizewindowCheckBox.Value;
            if optimizeEnabled
                app.WindowtimespreadsEditField.Enable = "off";
                optimizeWindowSigma(app);
                app.comm_counter = app.comm_counter + 1;
                app.InfoTable.Value{app.comm_counter} = 'Window optimized';
                scroll(app.InfoTable, 'bottom');
            else
                app.WindowtimespreadsEditField.Enable = "on";
                app.sigma = app.WindowtimespreadsEditField.Value * app.fs;
            end
            recalcStokes(app);
        end

        % Value changed function: SensorresponseEditField
        function SensorresponseEditFieldValueChanged(app, event)
            if app.signals_correct && strcmp(app.Switch.Value, 'Time domain')
                updateTimePlot(app);
            end
        
            app.comm_counter = app.comm_counter + 1;
            app.InfoTable.Value{app.comm_counter} = ...
                sprintf('Sensor response changed to %.6e', ...
                app.SensorresponseEditField.Value);
        
            scroll(app.InfoTable, 'bottom');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SeismicsignalpolarimetricanalysisUIFigure = uifigure('Visible', 'off');
            app.SeismicsignalpolarimetricanalysisUIFigure.Name = 'Seismic signal polarimetric analysis';
            app.S0Figure = uiaxes(app.SeismicsignalpolarimetricanalysisUIFigure);
            title(app.S0Figure, '$S_0$')
            xlabel(app.S0Figure, 'X')
            ylabel(app.S0Figure, 'Y')
            zlabel(app.S0Figure, 'Z')
            app.S0Figure.Visible = 'off';
            app.S1Figure = uiaxes(app.SeismicsignalpolarimetricanalysisUIFigure);
            title(app.S1Figure, '$S_1$')
            xlabel(app.S1Figure, 'X')
            ylabel(app.S1Figure, 'Y')
            zlabel(app.S1Figure, 'Z')
            app.S1Figure.Visible = 'off';
            app.timeDomainPlot = uiaxes(app.SeismicsignalpolarimetricanalysisUIFigure);
            title(app.timeDomainPlot, 'Time-domain signals')
            xlabel(app.timeDomainPlot, 'X')
            ylabel(app.timeDomainPlot, 'Y')
            zlabel(app.timeDomainPlot, 'Z')
            app.timeDomainPlot.Visible = 'off';
            app.S2Figure = uiaxes(app.SeismicsignalpolarimetricanalysisUIFigure);
            title(app.S2Figure, '$S_1$')
            xlabel(app.S2Figure, 'X')
            ylabel(app.S2Figure, 'Y')
            zlabel(app.S2Figure, 'Z')
            app.S2Figure.Visible = 'off';
            app.S3Figure = uiaxes(app.SeismicsignalpolarimetricanalysisUIFigure);
            title(app.S3Figure, '$S_1$')
            xlabel(app.S3Figure, 'X')
            ylabel(app.S3Figure, 'Y')
            zlabel(app.S3Figure, 'Z')
            app.S3Figure.Visible = 'off';
            app.LoadsignalsButton = uibutton(app.SeismicsignalpolarimetricanalysisUIFigure, 'push');
            app.LoadsignalsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsignalsButtonPushed, true);
            app.LoadsignalsButton.Text = 'Load signals';
            app.RunanalysisButton = uibutton(app.SeismicsignalpolarimetricanalysisUIFigure, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButtonPushed, true);
            app.RunanalysisButton.Text = 'Run analysis';
            app.CloseButton = uibutton(app.SeismicsignalpolarimetricanalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InfoTextAreaLabel = uilabel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTable = uitextarea(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.WindowtimespreadsEditFieldLabel = uilabel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.WindowtimespreadsEditFieldLabel.Text = 'Window time spread [s]';
            app.WindowtimespreadsEditField = uieditfield(app.SeismicsignalpolarimetricanalysisUIFigure, 'numeric');
            app.WindowtimespreadsEditField.Limits = [0 Inf];
            app.WindowtimespreadsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowtimespreadsEditFieldValueChanged, true);
            app.WindowtimespreadsEditField.Value = 40;
            app.BackazimuthdegEditFieldLabel = uilabel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.BackazimuthdegEditFieldLabel.Text = 'Backazimuth [deg]';
            app.BackazimuthdegEditField = uieditfield(app.SeismicsignalpolarimetricanalysisUIFigure, 'numeric');
            app.BackazimuthdegEditField.Limits = [0 360];
            app.BackazimuthdegEditField.ValueChangedFcn = createCallbackFcn(app, @BackazimuthdegEditFieldValueChanged, true);
            app.SamplingrateEditFieldLabel = uilabel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.SeismicsignalpolarimetricanalysisUIFigure, 'numeric');
            app.SamplingrateEditField.Limits = [0 Inf];
            app.SamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateEditFieldValueChanged, true);
            app.SamplingrateEditField.Value = 100;
            app.Switch = uiswitch(app.SeismicsignalpolarimetricanalysisUIFigure, 'slider');
            app.Switch.Items = {'Time domain', 'Frequency domain'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Time domain';
            app.InterpolationButtonGroup = uibuttongroup(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.InterpolationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InterpolationButtonGroupSelectionChanged, true);
            app.InterpolationButtonGroup.Title = 'Interpolation';
            app.NearestButton = uiradiobutton(app.InterpolationButtonGroup);
            app.NearestButton.Text = 'Nearest';
            app.NearestButton.Value = true;
            app.BilinearButton = uiradiobutton(app.InterpolationButtonGroup);
            app.BilinearButton.Text = 'Bilinear';
            app.ColormapListBoxLabel = uilabel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.ColormapListBoxLabel.Text = 'Colormap';
            app.ColormapListBox = uilistbox(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.ColormapListBox.Items = {'Default', 'Turbo', 'Parula', 'Hot'};
            app.ColormapListBox.ValueChangedFcn = createCallbackFcn(app, @ColormapListBoxValueChanged, true);
            app.ColormapListBox.Value = 'Default';
            app.SaturationEditFieldLabel = uilabel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.SaturationEditFieldLabel.Text = 'Saturation';
            app.SaturationEditField = uieditfield(app.SeismicsignalpolarimetricanalysisUIFigure, 'numeric');
            app.SaturationEditField.Limits = [0 1];
            app.SaturationEditField.ValueChangedFcn = createCallbackFcn(app, @SaturationEditFieldValueChanged, true);
            app.SaturationEditField.Value = 0.1;
            app.CleardataButton = uibutton(app.SeismicsignalpolarimetricanalysisUIFigure, 'push');
            app.CleardataButton.ButtonPushedFcn = createCallbackFcn(app, @CleardataButtonPushed, true);
            app.CleardataButton.Enable = 'off';
            app.CleardataButton.Text = 'Clear data';
            app.SavedataButton = uibutton(app.SeismicsignalpolarimetricanalysisUIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.TimeandfrequencylimitsPanel = uipanel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.TimeandfrequencylimitsPanel.Title = 'Time and frequency limits';
            app.TimestartsEditFieldLabel = uilabel(app.TimeandfrequencylimitsPanel);
            app.TimestartsEditFieldLabel.Text = 'Time start [s]';
            app.TimestartsEditField = uieditfield(app.TimeandfrequencylimitsPanel, 'numeric');
            app.TimestartsEditField.Limits = [0 Inf];
            app.TimestopsEditFieldLabel = uilabel(app.TimeandfrequencylimitsPanel);
            app.TimestopsEditFieldLabel.Text = 'Time stop [s]';
            app.TimestopsEditField = uieditfield(app.TimeandfrequencylimitsPanel, 'numeric');
            app.TimestopsEditField.Limits = [1 Inf];
            app.TimestopsEditField.Value = 1;
            app.FrequencystartHzEditFieldLabel = uilabel(app.TimeandfrequencylimitsPanel);
            app.FrequencystartHzEditFieldLabel.Text = 'Frequency start [Hz]';
            app.FrequencystartHzEditField = uieditfield(app.TimeandfrequencylimitsPanel, 'numeric');
            app.FrequencystartHzEditField.Limits = [0 Inf];
            app.FrequencystopHzEditFieldLabel = uilabel(app.TimeandfrequencylimitsPanel);
            app.FrequencystopHzEditFieldLabel.Text = 'Frequency stop [Hz]';
            app.FrequencystopHzEditField = uieditfield(app.TimeandfrequencylimitsPanel, 'numeric');
            app.FrequencystopHzEditField.Limits = [0 Inf];
            app.ApplyButton = uibutton(app.TimeandfrequencylimitsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.WavetypePanel = uipanel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.WavetypePanel.Title = 'Wave type';
            app.RayleighmodeCheckBox = uicheckbox(app.WavetypePanel);
            app.RayleighmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RayleighmodeCheckBoxValueChanged, true);
            app.RayleighmodeCheckBox.Text = 'Rayleigh mode';
            app.RayleighmodeCheckBox.Value = true;
            app.LovesmodeCheckBox = uicheckbox(app.WavetypePanel);
            app.LovesmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LovesmodeCheckBoxValueChanged, true);
            app.LovesmodeCheckBox.Text = 'Love''s mode';
            app.BandpassfilterPanel = uipanel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.BandpassfilterPanel.Title = 'Band-pass filter';
            app.LowerfrequencyHzEditFieldLabel = uilabel(app.BandpassfilterPanel);
            app.LowerfrequencyHzEditFieldLabel.Text = 'Lower frequency [Hz]';
            app.LowerfrequencyHzEditField = uieditfield(app.BandpassfilterPanel, 'numeric');
            app.LowerfrequencyHzEditField.Limits = [0.001 Inf];
            app.LowerfrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowerfrequencyHzEditFieldValueChanged, true);
            app.LowerfrequencyHzEditField.Value = 0.001;
            app.UpperfrequencyHzEditFieldLabel = uilabel(app.BandpassfilterPanel);
            app.UpperfrequencyHzEditFieldLabel.Text = 'Upper frequency [Hz]';
            app.UpperfrequencyHzEditField = uieditfield(app.BandpassfilterPanel, 'numeric');
            app.UpperfrequencyHzEditField.Limits = [0.001 Inf];
            app.UpperfrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @UpperfrequencyHzEditFieldValueChanged, true);
            app.UpperfrequencyHzEditField.Value = 0.001;
            app.DecimationfactorLabel = uilabel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.DecimationfactorLabel.Text = 'Decimation factor';
            app.DecimationfactorSlider = uieditfield(app.SeismicsignalpolarimetricanalysisUIFigure, 'numeric');
            app.DecimationfactorSlider.Limits = [1 300];
            app.DecimationfactorSlider.RoundFractionalValues = 'on';
            app.DecimationfactorSlider.ValueChangedFcn = createCallbackFcn(app, @DecimationfactorSliderValueChanged, true);
            app.DecimationfactorSlider.Value = 20;
            app.OptimizewindowCheckBox = uicheckbox(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.OptimizewindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimizewindowCheckBoxValueChanged, true);
            app.OptimizewindowCheckBox.Enable = 'off';
            app.OptimizewindowCheckBox.Text = 'Optimize window';
            app.SensorresponseEditFieldLabel = uilabel(app.SeismicsignalpolarimetricanalysisUIFigure);
            app.SensorresponseEditFieldLabel.Text = 'Sensor response';
            app.SensorresponseEditField = uieditfield(app.SeismicsignalpolarimetricanalysisUIFigure, 'numeric');
            app.SensorresponseEditField.Limits = [0 Inf];
            app.SensorresponseEditField.ValueDisplayFormat = '%e';
            app.SensorresponseEditField.ValueChangedFcn = createCallbackFcn(app, @SensorresponseEditFieldValueChanged, true);
            app.SensorresponseEditField.Value = 7.94e-10;
            % Show the figure after all components are created
            app.SeismicsignalpolarimetricanalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SeismicsignalpolarimetricanalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeismicsignalpolarimetricanalysisUIFigure)
        end
    end
end